classdef VRBPPT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VRBasedPsychologicalPhobiaTherapydcaroUIFigure  matlab.ui.Figure
        Label4                    matlab.ui.control.Label
        Label3                    matlab.ui.control.Label
        Label2                    matlab.ui.control.Label
        Label1                    matlab.ui.control.Label
        ResetdefaultsButton       matlab.ui.control.Button
        PortTextArea              matlab.ui.control.TextArea
        PortTextAreaLabel         matlab.ui.control.Label
        IPTextArea                matlab.ui.control.TextArea
        IPTextAreaLabel           matlab.ui.control.Label
        EEGdatatoshowButtonGroup  matlab.ui.container.ButtonGroup
        BothButton                matlab.ui.control.RadioButton
        RawdataButton             matlab.ui.control.RadioButton
        CleandataButton           matlab.ui.control.RadioButton
        LSLStreamInCheckBox       matlab.ui.control.CheckBox
        EmotionpredictionLabel    matlab.ui.control.Label
        PSDscaleSlider            matlab.ui.control.Slider
        PSDscaleSliderLabel       matlab.ui.control.Label
        EEGscaleSlider            matlab.ui.control.Slider
        EEGscaleLabel             matlab.ui.control.Label
        SignaltypeButtonGroup     matlab.ui.container.ButtonGroup
        RecordedButton            matlab.ui.control.RadioButton
        StreamedButton            matlab.ui.control.RadioButton
        TCPIPStreamOutCheckBox    matlab.ui.control.CheckBox
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

% VR-Based Psychological Phobia Therapy controlled by EEG.
% This apps deploys a Graphical User Interface that is able to receive data
% from the Aura software LSL Stream in two formats: 
%       1. Streamed signal (Cap on participant).
%       2. Recorded signal (File loaded).
% ------------------------------------------------------------------------
% The received signal is treated accordingly:
%   - If streamed, it goes directly into a preprocessing pipeline.
%   - If recorded, the signal is first "fixed", by trimming its fft and
%     applying ifft to recontruct the original signal (for more details,
%     contact: dgcarolp@hotmail.com). After that, it goes into the
%     preprocessing pipeline.
% ------------------------------------------------------------------------
% The preprocessing steps are:
%   1. Bandpass filter (1-50 Hz).
%   2. Common Average Reference.
%   3. Stopband filter (60 Hz).
%   4. WAAF. 
% ------------------------------------------------------------------------
% Once the data is clean, a Neural Network (CNN + LSTM) assigns one of
% these four labels to the window:
%       1. Surprise.
%       2. Relief.
%       3. Fear. 
%       4. Disgust. 
% ------------------------------------------------------------------------
% The data is received in 6-second chunks. After cleaning, only
% 5-second windows are preserved due to window edge artifacts, fixed by
% removing 0.5 seconds from the start and end of each original window.
% ------------------------------------------------------------------------
% This app avoids using a "while true [...]" statement by implementing a
% timer function ('dcaro_timer') and executing it every 5 seconds, for the 
% following reason:
%   The LSL stream provides the latest N samples (specified by the argument 
%   'chunksize', when calling the inlet constructor) and for this app, N is 
%   equivalent to 6 seconds. This doesn't cause any error, or overlap 
%   between windows, since we are only keeping 5 seconds due to edge 
%   artifacts. In the case that we were not rejecting these edges, we would 
%   have an overlap of 1 second, and I didn't want overlap.  
% ------------------------------------------------------------------------
% Finally, this app establishes a connection to a TCP/IP server with
% the IP and port provided (defaults: 'localhost:4000') to stream out the
% emotion label to any third party software.
% ------------------------------------------------------------------------
% Requirements:
%   - dcaro_aura_fix.m
%   - dcaro_WAAF.m
%   - dcaro_stacked.m
%   - Models/1_5-Nov-2025.mat
%   - liblsl-Matlab/
% 
% Please refer any query to: dgcarolp@hotmail.com or,
%                            A00833057@exatec.tec.mx
%_________________________________________________________________________
% by: Diego Caro López, Mirai Innovation Research Institute,
%     EMFUTECH Fall 2025, Osaka, Japan. 
%     12-Nov-2025.

    properties (Access = private)
        Fs                  % Aura EEG sample rate (250 Hz).
        Window_size         % EEG window size.
        IsRecorded          % 1 if file is loaded, otherwise, 1.
        Channels            % EEG channel labels.
        Classes             % Emotion labels.
        Net                 % Pre-train dcaro_net.
        CleanEEG            % Clean EEG window.
        RawEEG              % Raw EEG window.
        Scores              % Emotion predictions.
        RefWin              % Reference window of expected data size.
        Labels              % App emotions text labels.
        PSDData             % PSD of EEG window. 
        ShowClean           % 1 if Clean data || Both, otherwise, 0.
        ShowRaw             % 1 if Raw data || Both, otherwise, 0.
        Inlet               % LSL stream inlet. 
        StreamIn            % 1 if ready to receive LSL stream, otherwise, 0.
        StreamOut           % 1 if stream out emotion data via TCP/IP.
        IP                  % IP to connect socket.
        Port                % Port to connect socket.
        ClientOut           % TCP/IP Client object.
        EEGScale            % EEG scale slider value.
        PSDScale            % PSD scale slider value.
        dcaro_timer         % Update timer function.
    end
    
    methods (Access = private)
        
        function setDefaults(app)
            app.Fs = 250;
            app.Window_size = 6;
            app.IP = 'localhost';
            app.Port = 4000;
            app.Channels = {'Fp2','F7','F8','T7','T8','P7','P3','P8'};
            app.Classes = {'Surprise','Relief','Fear','Disgust'};
            app.Labels = cell(4,1);

            app.StreamOut = 0;
            app.StreamIn = 0;

            app.BothButton.Value = 1;
            app.ShowClean = 1;
            app.ShowRaw = 1;

            app.EEGScale = 200;
            app.EEGscaleSlider.Value = app.EEGScale;
            app.PSDScale = 30;
            app.PSDscaleSlider.Value = app.PSDScale;

            createRefWin(app);
            try
                app.plotPSDData();
                app.plotEEGData();
            catch 
                try
                    app.plotEEGData();
                catch
                end
            end
        end
        
        function dcaro_updates(app)
            chunk = app.Inlet.pull_chunk();   
            disp('Data received.')
            if isempty(chunk) || size(chunk,2) < size(app.RefWin,2)
                disp('Data rejected.')
                pause(0.05)
                return
            else
                if size(chunk,2) > size(app.RefWin,2)
                    chunk = chunk(:,end-size(app.RefWin,2)+1:end);
                end
                [app.CleanEEG,app.RawEEG] = dcaro_aura_fix(chunk,app.IsRecorded); 
                disp('Data cleaned.')
                app.Scores = predict(app.Net,app.CleanEEG);
                disp('Emotion predicted.')

                % Plot data here
                app.plotEEGData();
                app.plotPSDData();

                [~,id] = max(app.Scores);
                for i = 1:numel(app.Classes)
                    app.Labels{i} = [app.Classes{i} ': ' num2str(round(100*app.Scores(i),2)) '%'];
                end
                app.Label1.Text = app.Labels{1};
                app.Label2.Text = app.Labels{2};
                app.Label3.Text = app.Labels{3};
                app.Label4.Text = app.Labels{4};
                disp('Emotion values updated.')

                switch id
                    case 1
                        app.Label1.FontWeight = 'bold';
                        app.Label2.FontWeight = 'normal';
                        app.Label3.FontWeight = 'normal';
                        app.Label4.FontWeight = 'normal';
                    case 2
                        app.Label1.FontWeight = 'normal';
                        app.Label2.FontWeight = 'bold';
                        app.Label3.FontWeight = 'normal';
                        app.Label4.FontWeight = 'normal';
                    case 3
                        app.Label1.FontWeight = 'normal';
                        app.Label2.FontWeight = 'normal';
                        app.Label3.FontWeight = 'bold';
                        app.Label4.FontWeight = 'normal';
                    case 4
                        app.Label1.FontWeight = 'normal';
                        app.Label2.FontWeight = 'normal';
                        app.Label3.FontWeight = 'normal';
                        app.Label4.FontWeight = 'bold';
                    otherwise
                        beep
                        msgbox('Warning: Emotion not assigned.')
                end
                if app.StreamOut
                    try
                        write(app.ClientOut,uint8(id))
                        disp('Emotion sent.')
                    catch
                        beep
                        msgbox('Warning: Emotion not sent.')
                    end
                end
            end 
        end
        
        function createRefWin(app)
            if app.IsRecorded
                app.RefWin = zeros(size(app.Channels,2),8*app.Fs*app.Window_size);
            else
                app.RefWin = zeros(size(app.Channels,2),app.Fs*app.Window_size);
            end
        end
        
        function plotEEGData(app)
            if ~isempty(app.CleanEEG)
                cla(app.UIAxes,'reset')
            end

            if app.ShowRaw && ~app.ShowClean
                dcaro_stacked(app.RawEEG,'fs',app.Fs,'scale',app.EEGScale, ...
                    'labels',app.Channels,'target',app.UIAxes,'scalecol','r')
                title(app.UIAxes,'Raw EEG window')
            elseif app.ShowClean && ~app.ShowRaw
                dcaro_stacked(app.CleanEEG,'fs',app.Fs,'scale',app.EEGScale, ...
                    'labels',app.Channels,'target',app.UIAxes,'scalecol','r')
                title(app.UIAxes,'Clean EEG window')
            elseif app.ShowClean && app.ShowRaw
                dcaro_stacked(app.CleanEEG,'fs',app.Fs,'scale',app.EEGScale, ...
                    'labels',app.Channels,'target',app.UIAxes,'scalecol','r')
                dcaro_stacked(app.RawEEG,'fs',app.Fs,'scale',app.EEGScale,'labels', ...
                    app.Channels,'ref',1:8,'color','r','target',app.UIAxes,'lw',2,'scalecol','none')
                title(app.UIAxes,'Clean & Raw EEG window')
            end
            app.UIAxes.XTick = [1:4 4.99];
            app.UIAxes.XTickLabel = {'1','2','3','4','5'};
            box(app.UIAxes,'on')
        end
        
        function plotPSDData(app)
            if ~isempty(app.PSDData)
                cla(app.UIAxes2,'reset') 
            end

            [app.PSDData,f] = pmtm(app.CleanEEG',3,[],app.Fs);
            plot(app.UIAxes2,f,app.PSDData)
            xlim(app.UIAxes2,[0 50])
            ylim(app.UIAxes2,[0 app.PSDScale])

            title(app.UIAxes2,'PSD of EEG window ')
            xlabel(app.UIAxes2,'Frequency (Hz)')
            ylabel(app.UIAxes2,'Power/frequency (dB/Hz)')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load Models\1_5-Nov-2025.mat dcaro_net
            app.Net = dcaro_net;
            app.StreamedButton.Value = 1;
            app.IsRecorded = 0;

            title(app.UIAxes,'EEG window')
            app.UIAxes.XTick = [1:4 4.99];
            app.UIAxes.XTickLabel = {'1','2','3','4','5'};

            title(app.UIAxes2,'PSD of EEG window ')
            xlabel(app.UIAxes2,'Frequency (Hz)')
            ylabel(app.UIAxes2,'Power/frequency (dB/Hz)')
            xlim(app.UIAxes2,[0 50])

            app.setDefaults();
        end

        % Value changed function: TCPIPStreamOutCheckBox
        function TCPIPStreamOutCheckBoxValueChanged(app, event)
            app.StreamOut = app.TCPIPStreamOutCheckBox.Value;
            if app.StreamOut
                try
                    disp('Client started.')
                    app.ClientOut = tcpclient(app.IP,app.Port);
                catch
                    app.ClientOut = [];
                    beep
                    msgbox('Could not start TCP/IP client.')
                end            
            else
                delete(app.ClientOut)
            end
        end

        % Value changed function: IPTextArea
        function IPTextAreaValueChanged(app, event)
            app.IP = app.IPTextArea.Value;
        end

        % Value changed function: PortTextArea
        function PortTextAreaValueChanged(app, event)
            app.Port = str2double(app.PortTextArea.Value);
        end

        % Button pushed function: ResetdefaultsButton
        function ResetdefaultsButtonPushed(app, event)
            app.setDefaults();
        end

        % Value changed function: LSLStreamInCheckBox
        function LSLStreamInCheckBoxValueChanged(app, event)
            app.StreamIn = app.LSLStreamInCheckBox.Value;
            if app.StreamIn
                createRefWin(app);
                lib = lsl_loadlib();
                result = {};
                while isempty(result)
                    result = lsl_resolve_byprop(lib,'type','EEG');
                end

                app.Inlet = lsl_inlet(result{1},[],size(app.RefWin,2),0);
                app.Inlet.open_stream
                app.Inlet.set_postprocessing(15)
                
                app.dcaro_timer = timer('ExecutionMode','fixedSpacing', ...
                    'Period',5,'TimerFcn',@(~,~)dcaro_updates(app));
                start(app.dcaro_timer)
            else
                stop(app.dcaro_timer)
                delete(app.dcaro_timer)
                clear app.dcaro_timer
                app.Inlet.close_stream
                clear app.Inlet
                beep
                msgbox('LSL stream closed.')
            end
        end

        % Selection changed function: SignaltypeButtonGroup
        function SignaltypeButtonGroupSelectionChanged(app, event)
            app.IsRecorded = app.RecordedButton.Value;
            app.createRefWin();
        end

        % Selection changed function: EEGdatatoshowButtonGroup
        function EEGdatatoshowButtonGroupSelectionChanged(app, event)
            app.ShowClean = app.CleandataButton.Value || app.BothButton.Value;
            app.ShowRaw = app.RawdataButton.Value || app.BothButton.Value;
            try
                app.plotEEGData();
            catch
            end
        end

        % Value changed function: EEGscaleSlider
        function EEGscaleSliderValueChanged(app, event)
            value = app.EEGscaleSlider.Value;
            dcaro_vals = 50:15:350;
            [~,idx] = min(abs(dcaro_vals - value));
            app.EEGScale = dcaro_vals(idx);
            app.EEGscaleSlider.Value = app.EEGScale;
            try
                app.plotEEGData();
            catch
            end
        end

        % Value changed function: PSDscaleSlider
        function PSDscaleSliderValueChanged(app, event)
            value = app.PSDscaleSlider.Value;
            dcaro_vals = 10:5:50;
            [~,idx] = min(abs(dcaro_vals - value));
            app.PSDScale = dcaro_vals(idx);
            app.PSDscaleSlider.Value = app.PSDScale;
            try
                app.plotPSDData();
            catch
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VRBasedPsychologicalPhobiaTherapydcaroUIFigure = uifigure('Visible', 'off');
            app.VRBasedPsychologicalPhobiaTherapydcaroUIFigure.Name = 'VR-Based Psychological Phobia Therapy - dcaro';
            app.UIAxes = uiaxes(app.VRBasedPsychologicalPhobiaTherapydcaroUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Channels')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.VRBasedPsychologicalPhobiaTherapydcaroUIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'Frequency (Hz)')
            ylabel(app.UIAxes2, 'Power/frequency (dB/Hz)')
            zlabel(app.UIAxes2, 'Z')
            app.TCPIPStreamOutCheckBox = uicheckbox(app.VRBasedPsychologicalPhobiaTherapydcaroUIFigure);
            app.TCPIPStreamOutCheckBox.ValueChangedFcn = createCallbackFcn(app, @TCPIPStreamOutCheckBoxValueChanged, true);
            app.TCPIPStreamOutCheckBox.Text = 'TCP/IP Stream Out';
            app.SignaltypeButtonGroup = uibuttongroup(app.VRBasedPsychologicalPhobiaTherapydcaroUIFigure);
            app.SignaltypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SignaltypeButtonGroupSelectionChanged, true);
            app.SignaltypeButtonGroup.Title = 'Signal type:';
            app.StreamedButton = uiradiobutton(app.SignaltypeButtonGroup);
            app.StreamedButton.Text = 'Streamed';
            app.StreamedButton.Value = true;
            app.RecordedButton = uiradiobutton(app.SignaltypeButtonGroup);
            app.RecordedButton.Text = 'Recorded';
            app.EEGscaleLabel = uilabel(app.VRBasedPsychologicalPhobiaTherapydcaroUIFigure);
            app.EEGscaleLabel.Text = 'EEG scale :';
            app.EEGscaleSlider = uislider(app.VRBasedPsychologicalPhobiaTherapydcaroUIFigure);
            app.EEGscaleSlider.Limits = [50 350];
            app.EEGscaleSlider.MajorTicks = [50 125 200 275 350];
            app.EEGscaleSlider.ValueChangedFcn = createCallbackFcn(app, @EEGscaleSliderValueChanged, true);
            app.EEGscaleSlider.Value = 200;
            app.PSDscaleSliderLabel = uilabel(app.VRBasedPsychologicalPhobiaTherapydcaroUIFigure);
            app.PSDscaleSliderLabel.Text = 'PSD scale:';
            app.PSDscaleSlider = uislider(app.VRBasedPsychologicalPhobiaTherapydcaroUIFigure);
            app.PSDscaleSlider.Limits = [10 50];
            app.PSDscaleSlider.MajorTicks = [10 20 30 40 50];
            app.PSDscaleSlider.ValueChangedFcn = createCallbackFcn(app, @PSDscaleSliderValueChanged, true);
            app.PSDscaleSlider.MinorTicks = [10 12.5 15 17.5 20 22.5 25 27.5 30 32.5 35 37.5 40 42.5 45 47.5 50];
            app.PSDscaleSlider.Value = 30;
            app.EmotionpredictionLabel = uilabel(app.VRBasedPsychologicalPhobiaTherapydcaroUIFigure);
            app.EmotionpredictionLabel.Text = 'Emotion prediction:';
            app.LSLStreamInCheckBox = uicheckbox(app.VRBasedPsychologicalPhobiaTherapydcaroUIFigure);
            app.LSLStreamInCheckBox.ValueChangedFcn = createCallbackFcn(app, @LSLStreamInCheckBoxValueChanged, true);
            app.LSLStreamInCheckBox.Text = 'LSL Stream In';
            app.EEGdatatoshowButtonGroup = uibuttongroup(app.VRBasedPsychologicalPhobiaTherapydcaroUIFigure);
            app.EEGdatatoshowButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EEGdatatoshowButtonGroupSelectionChanged, true);
            app.EEGdatatoshowButtonGroup.Title = 'EEG data to show:';
            app.CleandataButton = uiradiobutton(app.EEGdatatoshowButtonGroup);
            app.CleandataButton.Text = 'Clean data';
            app.RawdataButton = uiradiobutton(app.EEGdatatoshowButtonGroup);
            app.RawdataButton.Text = 'Raw data';
            app.BothButton = uiradiobutton(app.EEGdatatoshowButtonGroup);
            app.BothButton.Text = 'Both';
            app.BothButton.Value = true;
            app.IPTextAreaLabel = uilabel(app.VRBasedPsychologicalPhobiaTherapydcaroUIFigure);
            app.IPTextAreaLabel.Text = 'IP:';
            app.IPTextArea = uitextarea(app.VRBasedPsychologicalPhobiaTherapydcaroUIFigure);
            app.IPTextArea.ValueChangedFcn = createCallbackFcn(app, @IPTextAreaValueChanged, true);
            app.IPTextArea.Value = {'localhost'};
            app.PortTextAreaLabel = uilabel(app.VRBasedPsychologicalPhobiaTherapydcaroUIFigure);
            app.PortTextAreaLabel.Text = 'Port:';
            app.PortTextArea = uitextarea(app.VRBasedPsychologicalPhobiaTherapydcaroUIFigure);
            app.PortTextArea.ValueChangedFcn = createCallbackFcn(app, @PortTextAreaValueChanged, true);
            app.PortTextArea.Value = {'4000'};
            app.ResetdefaultsButton = uibutton(app.VRBasedPsychologicalPhobiaTherapydcaroUIFigure, 'push');
            app.ResetdefaultsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetdefaultsButtonPushed, true);
            app.ResetdefaultsButton.Text = 'Reset defaults';
            app.Label1 = uilabel(app.VRBasedPsychologicalPhobiaTherapydcaroUIFigure);
            app.Label1.Text = 'Label1';
            app.Label2 = uilabel(app.VRBasedPsychologicalPhobiaTherapydcaroUIFigure);
            app.Label2.Text = 'Label2';
            app.Label3 = uilabel(app.VRBasedPsychologicalPhobiaTherapydcaroUIFigure);
            app.Label3.Text = 'Label3';
            app.Label4 = uilabel(app.VRBasedPsychologicalPhobiaTherapydcaroUIFigure);
            app.Label4.Text = 'Label4';
            % Show the figure after all components are created
            app.VRBasedPsychologicalPhobiaTherapydcaroUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VRBPPT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VRBasedPsychologicalPhobiaTherapydcaroUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VRBasedPsychologicalPhobiaTherapydcaroUIFigure)
        end
    end
end